classdef CroutDecomposition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel_8                 matlab.ui.container.Panel
        HTML                    matlab.ui.control.HTML
        Panel_4                 matlab.ui.container.Panel
        Button_6                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        DemosLabel              matlab.ui.control.Label
        SolveButton             matlab.ui.control.Button
        LPanel                  matlab.ui.container.Panel
        UITable_3               matlab.ui.control.Table
        UPanel                  matlab.ui.container.Panel
        UITable_4               matlab.ui.control.Table
        UITable_2               matlab.ui.control.Table
        UITable                 matlab.ui.control.Table
        BPanel                  matlab.ui.container.Panel
        RemovecolumnButton      matlab.ui.control.Button
        AddcolumnButton         matlab.ui.control.Button
        APanel                  matlab.ui.container.Panel
        RemoveButton            matlab.ui.control.Button
        AddButton               matlab.ui.control.Button
        Panel_3                 matlab.ui.container.Panel
        ColumnsinBSpinner       matlab.ui.control.Spinner
        ColumnsinBSpinnerLabel  matlab.ui.control.Label
        SizeSpinner             matlab.ui.control.Spinner
        SizeLabel               matlab.ui.control.Label
        GenerateButton          matlab.ui.control.Button
        Panel_5                 matlab.ui.container.Panel
        UITable_8               matlab.ui.control.Table
        UITable_5               matlab.ui.control.Table
        XABCheckBox             matlab.ui.control.CheckBox
        AXBCheckBox             matlab.ui.control.CheckBox
        EditField               matlab.ui.control.EditField
        XPanel                  matlab.ui.container.Panel
        XPanel_2                matlab.ui.container.Panel
    end

    
    properties (Access = private)
        selectedRow
        selectedColumn
        selectedColumn_2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = zeros([3,3]);
            app.UITable_2.Data = zeros([3,1]);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.UITable.Data(app.selectedRow, :) = [];
            app.UITable.Data(:,app.selectedColumn) = [];
            app.UITable_2.Data(app.selectedRow, :) = [];
        end

        % Cell selection callback: UITable, UITable_2
        function UITableCellSelection(app, event)
            if(~isempty(event.Indices)) %bez tego warunku wywala program jak się kliknie w puste miejsce
                app.selectedRow = event.Indices(1);
                app.selectedColumn = event.Indices(2);
                app.selectedColumn_2 = event.Indices(2);
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.UITable.Data = [app.UITable.Data; zeros([1,width(app.UITable.Data)])];
            app.UITable.Data = [app.UITable.Data zeros(height(app.UITable.Data),1)]; % Concantencate array
            app.UITable_2.Data = [app.UITable_2.Data; zeros([1,width(app.UITable_2.Data)])];
        end

        % Button pushed function: AddcolumnButton
        function AddcolumnButtonPushed(app, event)
             app.UITable_2.Data = [app.UITable_2.Data zeros(height(app.UITable_2.Data),1)];
        end

        % Button pushed function: RemovecolumnButton
        function RemovecolumnButtonPushed(app, event)
            app.UITable_2.Data(:,app.selectedColumn_2) = [];
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            app.EditField.Value = "Are X matrices identical?";
            app.EditField.BackgroundColor =[ 1 1 1 ];
            app.EditField.FontColor = "black";
            [L,U] = CroutLU(app.UITable.Data)
            app.UITable_3.Data = L;
            app.UITable_4.Data = U;
            if(app.AXBCheckBox.Value)
                x1 = SolveMatrixEquation(app.UITable.Data,app.UITable_2.Data,true)
                app.UITable_5.Data = x1;
                if(~app.XABCheckBox.Value)
                    app.UITable_8.Data = [];
                end
            end
           if(app.XABCheckBox.Value)
                x2 = SolveMatrixEquation(app.UITable.Data,app.UITable_2.Data,false)
                app.UITable_8.Data = x2;
                if(~app.AXBCheckBox.Value)
                    app.UITable_5.Data = [];
                end
           end

           if(app.XABCheckBox.Value && app.AXBCheckBox.Value)
               if(all(abs(x1-x2)<1e-8))
                    app.EditField.Value = "Matrices are identical!";
                    app.EditField.BackgroundColor = [0.40 1.00 0.40];
               else
                   app.EditField.Value = "Matrices are not identical";
                   app.EditField.FontColor = "white";
                   app.EditField.BackgroundColor = [1 0.1 0.1];
               end
           end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UITable.Data = [1 2 3 4; 2 1 2 3 ; 3 2 1 2 ; 4 3 2 1];
            app.UITable_2.Data = [1 2; 3 4 ; 5 6 ; 7 8]; 
            app.XABCheckBox.Value = false
            app.AXBCheckBox.Value = true
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.UITable.Data = [3 1 4 1; 5 9 2 6 ; 5 3 5 8 ; 9 7 9 3];
            app.UITable_2.Data = [2 3 8 4 ; 6 2 6 4]; 
            app.XABCheckBox.Value = true;
            app.AXBCheckBox.Value = false;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.UITable.Data = [2 7 1; 8 2 8 ; 1 8 2];
            app.UITable_2.Data = diag(ones(1,3)); 
            app.XABCheckBox.Value = true;
            app.AXBCheckBox.Value = true;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.UITable.Data =  [1 1 ; -1 2];
            app.UITable_2.Data = [2 5 ; -5 7];
            app.XABCheckBox.Value = true;
            app.AXBCheckBox.Value = true;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.UITable.Data =  [ -62.1057   27.5795    3.0750; -75.2613  -96.7760    8.9044;  64.1993   79.1909   21.2884];
            app.UITable_2.Data = [  52.0872;   71.0694;  -23.4263];
            app.XABCheckBox.Value = false;
            app.AXBCheckBox.Value = true;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.UITable.Data =  [-6	-6	4	-2	-4	-7	-4	0	9	;
                                3	5	6	2	-9	1	-1	0	-7	;
                                1	1	1	8	-1	8	2	-5	-2	;
                                -6	-4	-1	7	-3	-2	8	-8	10	;
                                -9	9	3	8	1	4	0	-8	3	;
                                6	6	8	9	9	3	-1	8	8	;
                                6	8	4	7	-4	0	5	-5	0	;
                                -1	-8	1	-9	-3	0	0	8	-9	;
                                -8	-6	-4	-9	8	9	8	5	3	];
            app.UITable_2.Data = [-8	2	-8	3	-4	1	-3	-4	-6	;
                                    -9	2	10	6	-4	-7	-5	-3	-6	;
                                    8	4	-9	6	8	-6	6	-4	3	;
                                    -5	3	7	-9	-1	5	5	-6	-4	;
                                    -9	-1	7	-4	7	7	-4	-2	2	;
                                    7	-7	-9	4	-8	-9	2	4	9	;
                                    -7	6	1	0	8	6	-1	-5	5	;
                                    8	-5	9	10	-9	10	-8	4	4	;
                                    -8	4	-3	5	8	-3	-9	-1	10];
            app.XABCheckBox.Value = true;
            app.AXBCheckBox.Value = true;
        end

        % Value changed function: SizeSpinner
        function SizeSpinnerValueChanged(app, event)
            value = app.SizeSpinner.Value;
            app.ColumnsinBSpinner.Limits = [1 value]
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            n = app.SizeSpinner.Value 
            bcol = app.ColumnsinBSpinner.Value
            app.UITable.Data = randi(20,n,n) - 10
            app.UITable_2.Data = randi(20,n,bcol) - 10
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_5 = uipanel(app.UIFigure);
            app.XPanel_2 = uipanel(app.Panel_5);
            app.XPanel_2.Title = 'X';
            app.XPanel = uipanel(app.Panel_5);
            app.XPanel.Title = 'X';
            app.EditField = uieditfield(app.Panel_5, 'text');
            app.EditField.Placeholder = 'Are X matrices identical?';
            app.AXBCheckBox = uicheckbox(app.Panel_5);
            app.AXBCheckBox.Text = 'AX=B';
            app.AXBCheckBox.Value = true;
            app.XABCheckBox = uicheckbox(app.Panel_5);
            app.XABCheckBox.Text = 'XA=B';
            app.UITable_5 = uitable(app.Panel_5);
            app.UITable_5.ColumnEditable = true;
            app.UITable_8 = uitable(app.Panel_5);
            app.UITable_8.ColumnEditable = true;
            app.Panel_3 = uipanel(app.UIFigure);
            app.GenerateButton = uibutton(app.Panel_3, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.SizeLabel = uilabel(app.Panel_3);
            app.SizeLabel.Text = 'Size';
            app.SizeSpinner = uispinner(app.Panel_3);
            app.SizeSpinner.Limits = [0 Inf];
            app.SizeSpinner.ValueChangedFcn = createCallbackFcn(app, @SizeSpinnerValueChanged, true);
            app.SizeSpinner.Value = 3;
            app.ColumnsinBSpinnerLabel = uilabel(app.Panel_3);
            app.ColumnsinBSpinnerLabel.Text = 'Columns in B';
            app.ColumnsinBSpinner = uispinner(app.Panel_3);
            app.ColumnsinBSpinner.Limits = [1 10];
            app.ColumnsinBSpinner.Value = 1;
            app.APanel = uipanel(app.UIFigure);
            app.APanel.Title = 'A';
            app.AddButton = uibutton(app.APanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.APanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.BPanel = uipanel(app.UIFigure);
            app.BPanel.Title = 'B';
            app.AddcolumnButton = uibutton(app.BPanel, 'push');
            app.AddcolumnButton.ButtonPushedFcn = createCallbackFcn(app, @AddcolumnButtonPushed, true);
            app.AddcolumnButton.Text = 'Add column';
            app.RemovecolumnButton = uibutton(app.BPanel, 'push');
            app.RemovecolumnButton.ButtonPushedFcn = createCallbackFcn(app, @RemovecolumnButtonPushed, true);
            app.RemovecolumnButton.Text = 'Remove column';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnEditable = true;
            app.UITable_2.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Panel_4 = uipanel(app.UIFigure);
            app.UPanel = uipanel(app.Panel_4);
            app.UPanel.Title = 'U';
            app.UITable_4 = uitable(app.UPanel);
            app.UITable_4.ColumnEditable = true;
            app.LPanel = uipanel(app.Panel_4);
            app.LPanel.Title = 'L';
            app.UITable_3 = uitable(app.LPanel);
            app.UITable_3.ColumnEditable = true;
            app.SolveButton = uibutton(app.Panel_4, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.DemosLabel = uilabel(app.Panel_4);
            app.DemosLabel.Text = 'Demos:';
            app.Button = uibutton(app.Panel_4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1 ';
            app.Button_2 = uibutton(app.Panel_4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.Panel_4, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.Panel_4, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.Panel_4, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.Panel_4, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Panel_8 = uipanel(app.UIFigure);
            app.HTML = uihtml(app.Panel_8);
            app.HTML.HTMLSource = ' <p style="text-align: center;"> <font size="5"> Using Crout decomposition (<span style="color: #66B3FF">A</span>=<span style="color: #996600">L</span><span style="color: #F2B3FF">U</span>)  to solve matrix equations: <span style="color: #66B3FF">A</span><span style="color: #FF1940">X</span>=<span style="color: #0000B3">B</span> and <span style="color: #00FF00">X</span><span style="color: #66B3FF">A</span>=<span style="color: #0000B3">B</span> </font></p> ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CroutDecomposition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end